classdef kinematic_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        LeftPanel          matlab.ui.container.Panel
        theta7Slider       matlab.ui.control.Slider
        theta7SliderLabel  matlab.ui.control.Label
        theta6Slider       matlab.ui.control.Slider
        theta6SliderLabel  matlab.ui.control.Label
        theta5Slider       matlab.ui.control.Slider
        theta5SliderLabel  matlab.ui.control.Label
        theta4Slider       matlab.ui.control.Slider
        theta4SliderLabel  matlab.ui.control.Label
        theta3Slider       matlab.ui.control.Slider
        theta3SliderLabel  matlab.ui.control.Label
        theta2Slider       matlab.ui.control.Slider
        theta2SliderLabel  matlab.ui.control.Label
        theta1Slider       matlab.ui.control.Slider
        theta1SliderLabel  matlab.ui.control.Label
        Button             matlab.ui.control.Button
        RightPanel         matlab.ui.container.Panel
        UIAxes             matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        robotConfigs = [90 90 90 90 90 90 90].'
    end
    
    methods (Access = private)
        function plotRobot(app,theta1,theta2,theta3,theta4,theta5,theta6,theta7)
                  
            frame = 1;
            origin = [0;0;0];            
            base_height = 420; L1 = 420; L2 = 420; L3 = 420; L4 = 420; L5 = 420; L6 = 420;   
            newConfigs = [theta1;theta2;theta3;theta4;theta5;theta6;theta7];
            interPolatedConfigs = zeros(7,10);
            for i=1:7
                interPolatedConfigs(i,:) = linspace(app.robotConfigs(i),newConfigs(i),10);
            end
            disp(interPolatedConfigs)
            while frame < 10           
                cla(app.UIAxes, 'reset') 
                base = H(app,0,0,0,0,0,0);
                Hbase_1 = H(app,0,0,interPolatedConfigs(1,frame),0,0,base_height);
                H1_2 = H(app,0,-interPolatedConfigs(2,frame),0,0,0,L1);
                H2_3 = H(app,0,interPolatedConfigs(3,frame),0,L2,0,0);
                H3_4 = H(app,0,interPolatedConfigs(4,frame),0,0,0,L3);
                H4_5 = H(app,0,-interPolatedConfigs(5,frame),0,-L4,0,0);
                H5_6 = H(app,0,interPolatedConfigs(6,frame),0,0,0,L5);
                H6_7 = H(app,0,-interPolatedConfigs(7,frame),0,-L6,0,0);
                H_all = Hbase_1*H1_2*H2_3*H3_4*H4_5*H5_6*H6_7;
                
                plotFrame(app,base, "Base");
                hold(app.UIAxes,"on");
                plotFrame(app,base*Hbase_1, "1");
                plotArm(app,base,Hbase_1);
                plotFrame(app,base*Hbase_1*H1_2, "2");
                plotArm(app,base*Hbase_1,base*Hbase_1*H1_2);
                plotFrame(app,base*Hbase_1*H1_2*H2_3, "3");
                plotArm(app,base*Hbase_1*H1_2,base*Hbase_1*H1_2*H2_3);
                plotFrame(app,base*Hbase_1*H1_2*H2_3*H3_4, "4");
                plotArm(app,base*Hbase_1*H1_2*H2_3,base*Hbase_1*H1_2*H2_3*H3_4);
                plotFrame(app,base*Hbase_1*H1_2*H2_3*H3_4*H4_5, "5");
                plotArm(app,base*Hbase_1*H1_2*H2_3*H3_4,base*Hbase_1*H1_2*H2_3*H3_4*H4_5);
                plotFrame(app,base*Hbase_1*H1_2*H2_3*H3_4*H4_5*H5_6, "6");
                plotArm(app,base*Hbase_1*H1_2*H2_3*H3_4*H4_5,base*Hbase_1*H1_2*H2_3*H3_4*H4_5*H5_6);
                plotFrame(app,base*Hbase_1*H1_2*H2_3*H3_4*H4_5*H5_6*H6_7, "End effector");
                plotArm(app,base*Hbase_1*H1_2*H2_3*H3_4*H4_5*H5_6,base*Hbase_1*H1_2*H2_3*H3_4*H4_5*H5_6*H6_7);
                xlim(app.UIAxes,[-5000 5000]); ylim(app.UIAxes,[-5000 5000]); zlim(app.UIAxes,[0 5000]);
                grid(app.UIAxes,"on");
                xlabel(app.UIAxes,"X Axis"); ylabel(app.UIAxes,"Y Axis"); zlabel(app.UIAxes,"Z Axis");
                
                pbaspect(app.UIAxes,[1 1 1]);
                view(app.UIAxes,45,20)
                hold(app.UIAxes,"off");
                frame = frame+1;
                drawnow;
                pause(0.02);
            end
            app.robotConfigs = [theta1,theta2,theta3,theta4,theta5,theta6,theta7]
        end
        function plotArm(app,h1,h2)
            o1 = h1(1:3,4);
            o2 = h2(1:3,4);
            plot3(app.UIAxes,[o1(1), o2(1)], [o1(2), o2(2)], [o1(3), o2(3)], "LineWidth",2,"Color","#0072BD");
        end
        function plotFrame(app, homogenous, name)
            length = 100;
            points = [
                1 0 0;
                0 1 0;
                0 0 1;
            ] * length;
            colors = ["red", "green", "blue"];
            hold(app.UIAxes,"on");
            text(app.UIAxes, homogenous(1,4)+10, homogenous(2,4)+10, homogenous(3,4)+50, name)
            for i = 1:3
                transformed_point = homogenous * [points(i,:), 1].';
                plot3(app.UIAxes, [homogenous(1,4),transformed_point(1)], [homogenous(2,4),transformed_point(2)], [homogenous(3,4),transformed_point(3)], 'Color', colors(i));        
            end
        end
        
        function homogenous = H(app,rx,ry,rz,tx,ty,tz)
            rx = deg2rad(rx); ry = deg2rad(ry); rz = deg2rad(rz);
            Rx = [
                1 0 0;
                0 cos(rx) -sin(rx)
                0 sin(rx) cos(rx)
            ];
            Ry = [
                cos(ry) 0 sin(ry);
                0 1 0;
                -sin(ry) 0 cos(ry);
            ];
            Rz = [
                cos(rz) -sin(rz) 0;
                sin(rz) cos(rz) 0;
                0 0 1;
            ];
            R = Rx*Ry*Rz;
            T = [tx;ty;tz];
            homogenous = [R, T; [0 0 0], [1]];
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: not associated with a component
        function theta7SliderValueChanged(app, event)
            heta1 = app.theta1Slider.Value;
            
        end

        % Callback function: not associated with a component
        function theta2SliderValueChanged(app, event)
            value = app.theta2Slider.Value;
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            theta1 = app.theta1Slider.Value;
            theta2 = app.theta2Slider.Value;
            theta3 = app.theta3Slider.Value;
            theta4 = app.theta4Slider.Value;
            theta5 = app.theta5Slider.Value;
            theta6 = app.theta6Slider.Value;
            theta7 = app.theta7Slider.Value;
            cla(app.UIAxes, 'reset')            
            plotRobot(app,theta1,theta2,theta3,theta4,theta5,theta6,theta7);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {659, 659};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {325, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Button = uibutton(app.LeftPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.theta1SliderLabel = uilabel(app.LeftPanel);
            app.theta1SliderLabel.Text = 'theta1';
            app.theta1Slider = uislider(app.LeftPanel);
            app.theta1Slider.Limits = [0 180];
            app.theta1Slider.Value = 90;
            app.theta2SliderLabel = uilabel(app.LeftPanel);
            app.theta2SliderLabel.Text = 'theta2';
            app.theta2Slider = uislider(app.LeftPanel);
            app.theta2Slider.Limits = [0 180];
            app.theta2Slider.Value = 90;
            app.theta3SliderLabel = uilabel(app.LeftPanel);
            app.theta3SliderLabel.Text = 'theta3';
            app.theta3Slider = uislider(app.LeftPanel);
            app.theta3Slider.Limits = [0 180];
            app.theta3Slider.Value = 90;
            app.theta4SliderLabel = uilabel(app.LeftPanel);
            app.theta4SliderLabel.Text = 'theta4';
            app.theta4Slider = uislider(app.LeftPanel);
            app.theta4Slider.Limits = [0 180];
            app.theta4Slider.Value = 90;
            app.theta5SliderLabel = uilabel(app.LeftPanel);
            app.theta5SliderLabel.Text = 'theta5';
            app.theta5Slider = uislider(app.LeftPanel);
            app.theta5Slider.Limits = [0 180];
            app.theta5Slider.Value = 90;
            app.theta6SliderLabel = uilabel(app.LeftPanel);
            app.theta6SliderLabel.Text = 'theta6';
            app.theta6Slider = uislider(app.LeftPanel);
            app.theta6Slider.Limits = [0 180];
            app.theta6Slider.Value = 90;
            app.theta7SliderLabel = uilabel(app.LeftPanel);
            app.theta7SliderLabel.Text = 'theta7';
            app.theta7Slider = uislider(app.LeftPanel);
            app.theta7Slider.Limits = [0 180];
            app.theta7Slider.Value = 90;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = kinematic_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end